classdef PCA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        DatasetsButtonGroup  matlab.ui.container.ButtonGroup
        variablesButton_3    matlab.ui.control.RadioButton
        variablesButton_2    matlab.ui.control.RadioButton
        variablesButton      matlab.ui.control.RadioButton
        TresDimensiones      matlab.ui.control.UIAxes
        DosDimensiones       matlab.ui.control.UIAxes
        UnaDimension         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [Xcono, Vs] = calcularPCA(~, X)
            % Se calcula la media 
            media = mean(X);
            Xcono = X - media;
            % Se calcula la matriz de covarianza
            sigma = cov(Xcono);
            
            % Calcular valores y vectores caracteristicos
            [V, D] = eig(sigma);
            
            % Ordenar los vectores en funcion de los valores caracteristicos
            D_vecti = diag(D);
            [~, IDX] = sort(D_vecti, "descend");
            Vs = V(:, IDX);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: DatasetsButtonGroup
        function DatasetsButtonGroupSelectionChanged(app, event)
            selectedButton = app.DatasetsButtonGroup.SelectedObject;
            switch selectedButton.Text
                case '2 variables'
                    trn = load('Data_Synthetic_750x100x5C.mat').trn;
                case '3 variables'
                    trn = load('Data_Synthetic_1000x50x2C_V2.mat').trn;
                case '5 variables'
                    trn = load('Data_Synthetic_3000x50x3c.mat').trn;
            end
            X = trn.xc;
            [Xcono, Vs] = calcularPCA(app, trn.xc);
            [n, ~] = size(X);
            
            % Se grafica con una dimensión
            Y = Xcono * Vs(:, 1);
            scatter(app.UnaDimension, Y(:, 1), zeros(n, 1), [], 'blue', "filled");
            
            % Se grafica con dos dimensión
            Y = Xcono * Vs(:, 1:2);
            scatter(app.DosDimensiones, Y(:, 1), Y(:, 2), [], 'red', "filled");
            
            % Se grafica con tres dimensión
            Y = Xcono * Vs(:, 1:3);
            scatter3(app.TresDimensiones, Y(:, 1), Y(:, 2), Y(:, 3), [], 'green', "filled");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UnaDimension = uiaxes(app.UIFigure);
            title(app.UnaDimension, 'Un componente Principal')
            xlabel(app.UnaDimension, {'PCA1'; ''})
            zlabel(app.UnaDimension, 'Z')
            app.DosDimensiones = uiaxes(app.UIFigure);
            title(app.DosDimensiones, 'Dos Componentes Principales')
            xlabel(app.DosDimensiones, 'PCA1')
            ylabel(app.DosDimensiones, 'PCA2')
            zlabel(app.DosDimensiones, 'Z')
            app.TresDimensiones = uiaxes(app.UIFigure);
            title(app.TresDimensiones, 'Tres Compoentes Principales')
            xlabel(app.TresDimensiones, 'PCA1')
            ylabel(app.TresDimensiones, 'PCA2')
            zlabel(app.TresDimensiones, 'PCA3')
            app.DatasetsButtonGroup = uibuttongroup(app.UIFigure);
            app.DatasetsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatasetsButtonGroupSelectionChanged, true);
            app.DatasetsButtonGroup.Title = 'Datasets';
            app.variablesButton = uiradiobutton(app.DatasetsButtonGroup);
            app.variablesButton.Text = '2 variables';
            app.variablesButton.Value = true;
            app.variablesButton_2 = uiradiobutton(app.DatasetsButtonGroup);
            app.variablesButton_2.Text = '3 variables';
            app.variablesButton_3 = uiradiobutton(app.DatasetsButtonGroup);
            app.variablesButton_3.Text = '5 variables';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PCA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end